classdef flow_1d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimulationTypeButtonGroup  matlab.ui.container.ButtonGroup
        FibreButton                matlab.ui.control.ToggleButton
        ParticleButton             matlab.ui.control.ToggleButton
        phiEditField               matlab.ui.control.NumericEditField
        phiEditFieldLabel          matlab.ui.control.Label
        KEditField                 matlab.ui.control.NumericEditField
        KEditFieldLabel            matlab.ui.control.Label
        PauseButton                matlab.ui.control.Button
        PkPaSlider                 matlab.ui.control.Slider
        PkPaSliderLabel            matlab.ui.control.Label
        PasSlider                  matlab.ui.control.Slider
        DpmSlider                  matlab.ui.control.Slider
        D_pmLabel                  matlab.ui.control.Label
        VpSlider                   matlab.ui.control.Slider
        Km2Label                   matlab.ui.control.Label
        PasSliderLabel             matlab.ui.control.Label
        LmmSlider                  matlab.ui.control.Slider
        LmmSliderLabel             matlab.ui.control.Label
        StartAnimationButton       matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fibreCircles          % circle handles
        fibreSpacingX = 0.01; % m
        fibreSpacingY = 0.01; % m
        halfHeight = 0.2; % m, half-height of composite section
        IsRunning = false;      % Tracks if the animation is active
        RestartRequested = false; % Flag to signal a restart
        IsPaused = false; % true when paused
        isUpdating = false; %lock to prevent recursive callbacks
    end
    
    methods (Access = private)
        
        % draw and update fibres
        function updateFibres(app)

            new_phi = app.phiEditField.Value;
            
            L = app.LmmSlider.Value *1e-3;
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            
            % redraw composite section
            rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, L, 2*app.halfHeight], 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'k', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
            set(app.UIAxes, 'YTick', []);

            % set up second axes
            xlim(app.UIAxes_2, [0 L]);
            ylim(app.UIAxes_2, [0 app.PkPaSlider.Value]);
            hold(app.UIAxes_2, 'on');
            grid(app.UIAxes_2, 'on');

            % calculate number of fibres in X and Y
            numFibresX = floor(L / app.fibreSpacingX);
            numFibresY = floor(0.4 / app.fibreSpacingY);

            % calculate fibre radius based on porosity
            cell_width = L / numFibresX;
            max_radius = cell_width / 2 * 0.9; % 90% of cell half-width
            radius = max_radius * sqrt(1 - new_phi);
            diameter = 2 * radius;

            % draw grid of fibres
            x_centers = linspace(cell_width/2, L - cell_width/2, numFibresX);
            y_centers = linspace(0.18, -0.18, numFibresY);

            app.fibreCircles = gobjects(numFibresX, numFibresY); 
            for i = 1:numFibresX
                for j = 1:numFibresY
                    x_pos = x_centers(i) - radius;
                    y_pos = y_centers(j) - radius;

                    % a circle is a rectangle with curvature 1
                    app.fibreCircles(i,j) = rectangle(app.UIAxes, ...
                        'Position', [x_pos, y_pos, diameter, diameter], ...
                        'Curvature', [1,1], ...
                        'FaceColor', [app.halfHeight app.halfHeight app.halfHeight], ...
                        'EdgeColor', 'none');
                end
            end
            
            title(app.UIAxes, 'Adjust sliders and press Start');
            xlabel(app.UIAxes, 'Position along composite (m)');
            ylabel(app.UIAxes, ' ');
            hold(app.UIAxes, 'off');
            axis(app.UIAxes, 'equal')

            % second axes
            title(app.UIAxes_2, 'Pressure vs distance');
            xlabel(app.UIAxes_2, 'Position along composite (m)');
            ylabel(app.UIAxes_2, 'Pressure (kPa)');
        end

        % Callback for the porosity slider changing
        function phiSliderChanging(app)
            updateFibres(app);
        end

        % Callback for the length slider changing
        function LmSliderChanging(app)
            updateFibres(app); % Redraw fibres for new length
        end

       function updateKphiFromVpDp(app)
        % only run if not already in the middle of an update:
        if app.isUpdating, return; end
        app.isUpdating = true;
    
        % 1) read user sliders (in physical units)
        Vp = app.VpSlider.Value;        % 
        Dp = app.DpmSlider.Value *1e-6;        % [micro m]
        
        if app.SimulationTypeButtonGroup.SelectedObject == app.ParticleButton
         %ARTICLE MODE
        % example: K from Kozeny–Carman on a packed‐bed of spheres
         % 2) compute phi and K
        phi = 1- Vp;       
        K = ((1-Vp)^3)/ (4.2*(6*Vp/Dp )^2);
         else
        % FIBRE MODE
        % example: flow between parallel cylinders (Carman model)
        % porosity from cylinder packing in a square array:
        phi=1-Vp;
        K= Dp^2 * (1-Vp)^3 / (150*Vp);
        end

        app.phiEditField.Value = phi;
        app.KEditField.Value   = K;
    
        app.isUpdating = false;
       end

        function updateVpDpFromKphi(app)
        if app.isUpdating, return; end
        app.isUpdating = true;
    
        % 1) read user edit-fields
        phi = app.phiEditField.Value;
        K   = app.KEditField.Value;

        if app.SimulationTypeButtonGroup.SelectedObject == app.ParticleButton
        % invert the “sphere‐packed” model
        Vp = 1-phi;   % or solve for Vp if needed
        Dp = sqrt((K*4.2*36*Vp^2) / (1-Vp)^3);
       else
        % invert the cylinder‐array model
        Vp = 1 - phi;  
        Dp = sqrt(K*150*Vp / (1-Vp)^3); 
        end
    
        % 3) write back into sliders
        app.VpSlider.Value = Vp;
        app.DpmSlider.Value  = Dp *1e6;
    
        app.isUpdating = false;
    end
        

    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateFibres(app);
            updateKphiFromVpDp(app);
        end

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
            %restart logic
            % if animation is already running restart 

            if app.IsRunning
                app.RestartRequested = true;
                app.IsPaused          = false;
                app.PauseButton.Text  = 'Pause';
                return;
            end


            % if animation isnt running start it
            app.IsRunning = true;
            app.RestartRequested = false;
            app.StartAnimationButton.Text = 'Restart Animation';

            % Composite Properties
            L = app.LmmSlider.Value *1e-3;       % Length of the composite [m]
            % V_p = app.VpSlider.Value * 1e-12;     % volume fraction of the particle or fibre 
            % K = (1-V_p)^3/ (4.2*(6*V_p/D_p )^2)
            K = app.KEditField.Value;
            phi=app.phiEditField.Value;
            % D_p = app.DpmSlider.Value;     % Diameter of fibre or particle [-]
            % Fluid Properties
            mu = app.PasSlider.Value;      % Viscosity of the fluid [Pa.s]

            % Process Conditions
            Pin = app.PkPaSlider.Value * 1e3;     % Inlet pressure [Pa]
            Pout = 0;      % Outlet pressure [Pa]
            deltaP = Pin - Pout; % Pressure drop across the composite [Pa]

            % Simulation Parameters
            t_total = 1e6; % Total simulation time [s]
            n_steps = 1000; % Number of time steps
            dt = t_total / n_steps; % Time step size [s]

            % Darcy's Law

            % Calculate Darcy Velocity
            const_term = 2*K*deltaP/(mu*phi);

            % animation setup
            time_vec = 0:dt:t_total; % Time vector for the simulation
            x_front = zeros(size(time_vec)); % Initialize the position of the flow front

            % figure setup
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
            title(app.UIAxes, 'Flow Front Simulation (t = 0.00 s)');
            title(app.UIAxes_2, 'Pressure distribution');
            xlabel(app.UIAxes, 'Position along composite (m)');
            ylabel(app.UIAxes, ' ');
            xlabel(app.UIAxes_2, 'Position along composite (m)');
            ylabel(app.UIAxes_2, 'Pressure (kPa)');
            set(app.UIAxes, 'YTick', []);

            % Draw the empty composite
            rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, L, 2*app.halfHeight], 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'k', 'LineWidth', 1.5);
            newphi = app.DpmSlider.Value;
            updateFibres(app);

            % Draw the initial filled region (blue rectangle)
            filled_region = rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, 0, 2*app.halfHeight], 'FaceColor', [0.3 0.6 1], 'EdgeColor', 'none');

            % Draw the flow front (as a vertical red line)
            flow_front_line = line(app.UIAxes, [0 0], [-app.halfHeight app.halfHeight], 'Color', 'r', 'LineWidth', 2);
            uistack(app.fibreCircles(:), 'top');
            uistack(flow_front_line, 'top');

            % draw pressure lines
            const_dpdx_line = line(app.UIAxes_2, [0 0], [app.PkPaSlider.Value 0], 'Color', 'k', 'LineWidth', 2);
            const_p_line = line(app.UIAxes_2, [0 L], [0 0], 'Color', 'k', 'LineWidth', 2);

            x_new = sqrt(const_term * time_vec);
            x_new(x_new > L) = L;

            % main animation loop
            while app.IsRunning
                for i = 2:length(time_vec)

                    % pause check
                     while app.IsPaused && app.IsRunning
                     drawnow;  
                     end
                     % If they hit Stop/Restart, break out
                    if ~app.IsRunning || app.RestartRequested
                        app.RestartRequested = false;
                        break;
                    end

                    x_front(i)=x_new(i);

                    % Update the width of the blue rectangle
                    delete(filled_region);
                    filled_region = rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, x_front(i), 2*app.halfHeight], 'FaceColor', [0.3 0.6 1], 'EdgeColor', 'none', 'FaceAlpha', 0.3);

                    % Update the position of the flow front line
                    delete(flow_front_line);
                    flow_front_line = line(app.UIAxes, [x_front(i) x_front(i)], [-app.halfHeight app.halfHeight], 'Color', 'r', 'LineWidth', 2);

                    % Update the title
                    fill_percentage = (x_front(i) / L) * 100;

                    % pressure graph
                    delete(const_dpdx_line);
                    delete(const_p_line);
                    const_dpdx_line = line(app.UIAxes_2, [0 x_front(i)], [app.PkPaSlider.Value 0], 'Color', 'k', 'LineWidth', 2);
                    const_p_line = line(app.UIAxes_2, [x_front(i) L], [0 0], 'Color', 'k', 'LineWidth', 2);
                   
                    title(app.UIAxes, sprintf('Time: %.2f hrs | Fill: %.1f%%', time_vec(i)/3600, fill_percentage));

                    % Pause to control animation speed
                    drawnow

                    % Stop the animation if the composite is full 
                    % or if we move a slider
                    if x_front(i) >= L || app.RestartRequested
                         
                        app.RestartRequested = false;
                        break;
                    end
                end

                hold(app.UIAxes, 'off');
                disp('Animation complete.');

                app.IsRunning = false;
            end

        end

        % Value changed function: LmmSlider
        function LmmSliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Value changed function: VpSlider
        function VpSliderValueChanged(app, event)
            updateKphiFromVpDp(app);
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
            
        end

        % Value changed function: DpmSlider
        function DpmSliderValueChanged(app, event)
            updateKphiFromVpDp(app);
            
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end

            
        end

        % Value changed function: PasSlider
        function PasSliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Value changed function: PkPaSlider
        function PkPaSliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
           app.IsPaused = ~app.IsPaused;
           if app.IsPaused
            app.PauseButton.Text = 'Resume';
           else
            app.PauseButton.Text = 'Pause';
           end 
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
                % Sync sliders ↑↔ edit-fields
            updateVpDpFromKphi(app);
            % If animation is running, trigger a restart & redraw
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
            end
            
        end

        % Value changed function: phiEditField
        function phiEditFieldValueChanged(app, event)
                updateVpDpFromKphi(app);
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
            end
            
        end

        % Selection changed function: SimulationTypeButtonGroup
        function SimulationTypeButtonGroupSelectionChanged(app, event)

            updateKphiFromVpDp(app);
            % 2) redraw the fibre‐layout
            updateFibres(app);
            % 3) if you’re mid‐animation, restart it so the new K is used
            if app.IsRunning
                app.IsRunning = false;    % stop old run
                pause(0.01);              % let it unwind
                app.StartAnimationButtonPushed(); % kick off a fresh one
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.StartAnimationButton = uibutton(app.UIFigure, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.LmmSliderLabel = uilabel(app.UIFigure);
            app.LmmSliderLabel.Text = 'L (mm)';
            app.LmmSlider = uislider(app.UIFigure);
            app.LmmSlider.Limits = [0 200];
            app.LmmSlider.ValueChangedFcn = createCallbackFcn(app, @LmmSliderValueChanged, true);
            app.LmmSlider.Value = 100;
            app.PasSliderLabel = uilabel(app.UIFigure);
            app.PasSliderLabel.Text = 'μ (Pa.s)';
            app.Km2Label = uilabel(app.UIFigure);
            app.Km2Label.Text = 'Vp';
            app.VpSlider = uislider(app.UIFigure);
            app.VpSlider.Limits = [0 1];
            app.VpSlider.ValueChangedFcn = createCallbackFcn(app, @VpSliderValueChanged, true);
            app.VpSlider.Value = 1;
            app.D_pmLabel = uilabel(app.UIFigure);
            app.D_pmLabel.Text = 'Dp (μm)';
            app.DpmSlider = uislider(app.UIFigure);
            app.DpmSlider.ValueChangedFcn = createCallbackFcn(app, @DpmSliderValueChanged, true);
            app.DpmSlider.Value = 50;
            app.PasSlider = uislider(app.UIFigure);
            app.PasSlider.Limits = [0 50];
            app.PasSlider.ValueChangedFcn = createCallbackFcn(app, @PasSliderValueChanged, true);
            app.PasSlider.Value = 50;
            app.PkPaSliderLabel = uilabel(app.UIFigure);
            app.PkPaSliderLabel.Text = 'P (kPa)';
            app.PkPaSlider = uislider(app.UIFigure);
            app.PkPaSlider.ValueChangedFcn = createCallbackFcn(app, @PkPaSliderValueChanged, true);
            app.PkPaSlider.Value = 50;
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.phiEditField.ValueChangedFcn = createCallbackFcn(app, @phiEditFieldValueChanged, true);
            app.phiEditField.Value = 0.5;
            app.SimulationTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SimulationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationTypeButtonGroupSelectionChanged, true);
            app.SimulationTypeButtonGroup.Title = 'Simulation Type';
            app.ParticleButton = uitogglebutton(app.SimulationTypeButtonGroup);
            app.ParticleButton.Text = 'Particle';
            app.ParticleButton.Value = true;
            app.FibreButton = uitogglebutton(app.SimulationTypeButtonGroup);
            app.FibreButton.Text = 'Fibre';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flow_1d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end